classdef lte < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        Tab2                 matlab.ui.container.Tab
        LTELTEASimulationParametersPanel  matlab.ui.container.Panel
        SetParametersButton  matlab.ui.control.Button
    end


    properties (AbortSet)  % <- stops calling of .set when same data entered
        % Cell array of data
        %   - 4 elements are required for this app
        DataSet = {}
    end

    properties (SetAccess=private)
        DP           % handle to DialogPresenter
        Dialogs      % vector of handles to all Dialogs
        Listeners
    end

    properties % (Access=private)
        hFig         % handle to HG figure
        hTop         % handle to top-level uicontainer
        hTheDamnLines  % vector of handles to the lines, one per subplot
    end

    methods
        function dt = lte_mainObj(app, varargin)
            % Pass one or more dialogs to the constructor:
            %   dt = DialogTester(dlg1,dlg2,...)
            %   dt = DialogTester([dlg1 dlg2 ...])
            %
            % Set property values for the DialogPresenter by passing them
            % as trailing arguments to the constructor:
            %    dt = DialogTester(...,'Param',value,...)
            %
            % To identify DialogPresenter properties, look at:
            %    dt.DP
            
            % instantiate all of our dialogs here:
            %  xxx add EVM readout later
            dt.Dialogs = [dlg_getParams dlg_showMeasures];
%             dt.Dialogs = [dlg_getParams dlg_showMeasures];
%             dt.Dialogs = [dlg_getParams dlg_showMeasures dlg_Accelerate];
            init(app, dt);
        end
        
        function set.DataSet(app,val)
            % called whenever DataSet property changes.
            
            %validateattributes();
            
            app.DataSet = val; % store the new value
            
            % Call a side-effect:
            updateThePlots(app, app);
        end
    end

    methods (Access=private)
        function init(app, dt)
            % Initialize overall UI
            % - create dialog panel
            % - create app body with simple plot
            
            % Create UI
            fig = figure( ...
                'NumberTitle','off', ...
                'Name','LTE/LTE-A Testbench', ...
                'IntegerHandle','off', ...
                'menubar','none', ...
                'pos', [422 2 850 700]);
            
            dt.hFig = fig;
            hHigher = uicontainer( ...
                'parent',fig, ...
                'tag','DialogTester_hTop_Parent', ...
                'pos',[0 0 1 1]);
            dt.hTop = uicontainer( ...
                'parent',hHigher, ...
                'tag','DialogTester_hTop', ...
                'pos',[0 0 1 .98]);
            
            createDP(app, dt);
            addDialogs(app, dt);
            
            createAppBody(app, dt);
            finalizeDialogs(app, dt); % makes DialogPanel visible when finished
            initListeners(app, dt);
            
            % Enable UI
            setVisible(dt.DP,true);
        end
        
        function initListeners(app, dt)
            % Create listeners in disabled state.
            
            % Suppress keypresses (ordinarily, keypresses over the app
            % would echo to the command window)
            hfig = dt.hFig;
            hfig.KeyPressFcn = @(~,~)[];
            
%             lis.MouseMotion  = addlistener(hfig,'WindowMouseMotion', @(h,e)mouseMove(dt,h,e));
%             lis.MousePress   = addlistener(hfig,'WindowMousePress',  @(~,~)mouseDown(dt));
%             lis.MouseRelease = addlistener(hfig,'WindowMouseRelease',@(~,~)mouseUp(dt));
%             lis.ScrollWheel  = addlistener(hfig,'WindowScrollWheel', @(~,e)wheelMove(dt,e));
%             dt.Listeners = lis;
        end
        
%         function createAppBody(app, app)
%             % This is the test app
%             
%             theDP = app.DP;        % Get handle to DialogPanel
%             app.hFig = theDP.hFig; % copy for convenience
%             parent = theDP.hBodyPanel;
%             
%             % App 2: multiple axes
%             for i = 1:2
%                 hax_i = subplot(2,1,i,'parent',parent);
%                 hline(i) = line('parent',hax_i, ...
%                     'xdata',[], ...
%                     'ydata',[]);
%             end
%             app.hTheDamnLines = hline;
%             
%         end
        
        function createDP(app, dt)
            % Create DPVerticalPanel object that is owned by tester.
            % Panel is left invisible until after dialogs are added.
            
            % Suppress automatic call to init() by not passing hTop as
            % input arg. Just call init() manually after properties are
            % set.
            theDP = dialogmgr.DPVerticalPanel;
            
            % Ordered list of names of visible dialogs
            theDP.DockedDialogNamesInit = {dt.Dialogs.Name};
            theDP.DialogBorderFactory = @dialogmgr.DBTopBar;
            theDP.DialogVerticalGutter = 8;
            theDP.DialogHorizontalGutter = 8;
            theDP.Animation = true;
            theDP.PanelMinWidth = 200;
            theDP.PanelMaxWidth = 450;
            theDP.PanelWidth = 250;
            theDP.BodyMinHeight = 100;
            %theDP.BodyMinWidth = 100;
            theDP.DialogBorderDecoration = ...
                {'TitlePanelBackgroundColorSource','Custom', ...
                'TitlePanelBackgroundColor',[.1 .1 .8]};
            
            % Cross-reference DialogTester and DialogPresenter
            dt.DP = theDP;
            theDP.UserData = dt;
            
            init(theDP,dt.hTop);
        end
        
        function addDialogs(app, dt)
            % Create application dialogs and add to Dialog Manager.
            
            dlgs = dt.Dialogs;
            N = numel(dlgs);
            for i = 1:N
                d_i = dlgs(i);
                createAndRegisterDialog(dt.DP,d_i);
                
                % For each dialog, any change to a control can call a
                % function.  It can be a different function for each
                % dialog.  For simplicity, we call the SAME function for
                % ALL dialogs in this test app:
                onPropertyChange(d_i, @(h,ev)valuesUpdated(dt,ev));
            end
            
            % Dialogs(2) is the dlg_metrics readout
            metrics = dt.Dialogs(2);
            %onPropertyChange(metrics, @(h,ev)update(metrics))
            onPropertyChange(metrics, @(h,ev)disp('change'));
        end
        
        function finalizeDialogs(app, dt)
            % Leaves GUI ready for user interaction, but invisible
            
            theDP = dt.DP;
            
            % First step of init is to finalize dialogs for dialog panel
            % -> no more dialogs should be registered after this point!
            finalizeDialogRegistration(theDP);
            
            % true: show panel
            % false: no animation
            setDialogPanelVisible(theDP,true,false);
            
            % Initialize pointer
            setptr(theDP.hFig,'arrow');
            
            % Add a resize callback if desired:
            % set(theDP.hBodyPanel,'ResizeFcn',@resizeFcn);
        end
        
    end
    methods (Access=private)
        function valuesUpdated(app, dt,ev)
            % Do whatever the App wants to do in response to a change in
            % dialog parameters.
            
            % Here's how to get the specific property that changed, and its
            % new value:
            theDialog = ev.Source;
            theProperty = ev.Property;
            newValue = theDialog.(theProperty);
            
            % Show the change in the command window:
            valueStr = mat2str(newValue);
            %fprintf('Property "%s" changed to %s\n',theProperty,valueStr);
        end
    end
    
    methods
        function updateThePlots(app)
            % Update the line plots
            
            ds = app.DataSet;
            hlines = app.hTheDamnLines;
            N = numel(ds);
            for i = 1:N
                data_i = ds{i};
                Nx = numel(data_i);
                hlines(i).XData = (1:Nx);
                hlines(i).YData = data_i;
            end
            drawnow expose
        end
        
        function updateMetrics(app, dt,values)
            % updateMetrics(h,values) updates RF Metrics readout.
            %
            % 1 to 4 values may be passed in a vector:
            %
            %    values = [Frame# BER EVM ACLR]
            %           = [Frame# BER EVM]
            %           = [Frame# BER]
            %           = [Frame#]
            % Note that EVM is a fraction, not a percent.
            
            metricsDlg = dt.Dialogs(2);
            metricsDlg.Values(1:numel(values)) = values;
            update(metricsDlg);
        end
    end

    methods (Access = private)

        % Callback function
        function dlg_getParams(app, event)
            position = app.LTELTEASimulationParametersPanel.Position;
            if event == 'push'
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab.Units = 'pixels';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.Units = 'pixels';
            app.LTELTEASimulationParametersPanel = uipanel(app.UIFigure);
            app.LTELTEASimulationParametersPanel.Title = 'LTE/LTE-A Simulation Parameters';
            app.SetParametersButton = uibutton(app.LTELTEASimulationParametersPanel, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @dlg_getParams, true);
            app.SetParametersButton.Text = 'Set Parameters';
        end
    end

    methods (Access = public)

        % Construct app
        function app = lte()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end